classdef MusicAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        AnalyzeButton    matlab.ui.control.Button
        FileLabel        matlab.ui.control.Label
        ChordLabel       matlab.ui.control.Label
        BPMLabel         matlab.ui.control.Label
        LoadMusicButton  matlab.ui.control.Button
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    audioData   
    Fs          
    filePath    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            root = fileparts(mfilename('fullpath'));
            addpath(genpath(fullfile(root, "src")));
        end

        % Button pushed function: LoadMusicButton
        function LoadMusicButtonPushed(app, event)
                try
        [file, path] = uigetfile({'*.wav;*.mp3'}, 'Select Audio File');
        if isequal(file,0)
            return;
        end

        app.filePath = fullfile(path, file);

        [y, Fs] = audioread(app.filePath);

        if size(y,2) == 2
            y = mean(y,2); 
        end

        app.audioData = y;
        app.Fs = Fs;

        maxSec = 10;
        Nmax = min(length(y), maxSec*Fs);
        y_plot = y(1:Nmax);

        dec = 10;
        y_plot = y_plot(1:dec:end);
        t_plot = (0:length(y_plot)-1) / (Fs/dec);

        reset(app.UIAxes2);
        hold(app.UIAxes2, 'off');
               
        plot(app.UIAxes, t_plot, y_plot);
        title(app.UIAxes, "Waveform (first 10s, downsampled)");
        xlabel(app.UIAxes, "Time (s)");
        ylabel(app.UIAxes, "Amplitude");
        drawnow;                        

        app.FileLabel.Text = "File: " + file;

    catch ME
        uialert(app.UIFigure, ME.message, "Load Error");
        disp(getReport(ME, "extended"));
    end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            try
        y = app.audioData;
        Fs = app.Fs;

        [bpm, cand] = estimateBPM_Pro2(y, Fs);
        app.BPMLabel.Text = "BPM: " + string(round(bpm));
        disp(cand);

        [key, tonic, bass, bassStab, chromaRoot] = detectKey_Final(y, Fs);
        app.ChordLabel.Text = "Key: " + key;

        cla(app.UIAxes2);
        
        labelBass   = sprintf('1 - Bass (%s)',   bass);
        labelTonic  = sprintf('2 - Tonic (%s)',  tonic);
        labelChroma = sprintf('3 - Chroma (%s)', chromaRoot);

        labelCell = {labelBass, labelTonic, labelChroma};

        labels = categorical(labelCell);
        labels = reordercats(labels, labelCell);

        values = [
            double(bassStab);
            1.0;
            0.8
        ];
        
        bar(app.UIAxes2, labels, values, 'FaceColor', '#0072BD');
        ylabel(app.UIAxes2, "Confidence Level");
        title(app.UIAxes2, "Bass - Tonic - Chroma Comparison");
        ylim(app.UIAxes2, [0 1]);


    catch ME
        uialert(app.UIFigure, ME.message, "Analyze Error");
        disp(getReport(ME, "extended"));
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'ComparisonAxes')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadMusicButton = uibutton(app.UIFigure, 'push');
            app.LoadMusicButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMusicButtonPushed, true);
            app.LoadMusicButton.Text = 'Load Music';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM: -';
            app.ChordLabel = uilabel(app.UIFigure);
            app.ChordLabel.Text = 'Chord: -';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = 'File: -';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MusicAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end